classdef ScoreObjectiveDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WildlifeScoringObjectivesUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        RuleSettingPanel     matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        FoxDropDown          matlab.ui.control.DropDown
        FoxDropDownLabel     matlab.ui.control.Label
        HawkDropDown         matlab.ui.control.DropDown
        HawkDropDownLabel    matlab.ui.control.Label
        SalmonDropDown       matlab.ui.control.DropDown
        SalmonDropDownLabel  matlab.ui.control.Label
        BearImage            matlab.ui.control.Image
        ElkDropDown          matlab.ui.control.DropDown
        ElkDropDownLabel     matlab.ui.control.Label
        BearDropDown         matlab.ui.control.DropDown
        BearDropDownLabel    matlab.ui.control.Label
        HawkImage            matlab.ui.control.Image
        SalmonImage          matlab.ui.control.Image
        FoxImage             matlab.ui.control.Image
        ElkImage             matlab.ui.control.Image
    end


    properties (Access = private)
        MainApp
        GameMode
        ScoringRules
    end
    
    methods (Access = private)
        
        function syncImageToDropdown(app)
            %{
            if isempty(gameMode) || (gameMode ~= GameModeEnum.FamilyVariant && ...
                    gameMode ~= GameModeEnum.IntermediateVariant)
                % Assign user data to link drop-down menu to image
                app.BearDropDown.UserData = AnimalEnum.Bear;
                app.ElkDropDown.UserData = AnimalEnum.Elk;
                app.SalmonDropDown.UserData = AnimalEnum.Salmon;
                app.HawkDropDown.UserData = AnimalEnum.Hawk;
                app.FoxDropDown.UserData = AnimalEnum.Fox;

                app.BearImage.UserData = AnimalEnum.Bear;
                app.ElkImage.UserData = AnimalEnum.Elk;
                app.SalmonImage.UserData = AnimalEnum.Salmon;
                app.HawkImage.UserData = AnimalEnum.Hawk;
                app.FoxImage.UserData = AnimalEnum.Fox;
            end
            %}
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, gameMode, scoringRules)
            app.MainApp = mainApp;
            app.GameMode = gameMode;
            app.ScoringRules = scoringRules;

            dropDownArray = [app.BearDropDown, app.ElkDropDown, app.SalmonDropDown, ...
                app.HawkDropDown, app.FoxDropDown];

            switch gameMode
                case GameModeEnum.RandomRules
                    for i = 1:length(dropDownArray)
                        if isempty(scoringRules)
                            dropDownArray(i).Items = {'TBD'};
                        else
                            numToLet = char(scoringRules(i) + 64);
                            dropDownArray(i).Items = {numToLet};
                        end
                    end
                    syncImageToDropdown(app);
                case {GameModeEnum.FamilyVariant, GameModeEnum.IntermediateVariant}


                case GameModeEnum.CustomRules
                    for i = 1:length(dropDownArray)
                        dropDownArray(i).Enable = true;
                        numToLet = char(scoringRules(i) + 64);
                        dropDownArray(i).Value = numToLet;
                    end
                    syncImageToDropdown(app);
            end       

            % Set location same as parent dialog
            app.WildlifeScoringObjectivesUIFigure.Position(1:2) = mainApp.CascadiaUIFigure.Position(1:2);
        end

        % Close request function: WildlifeScoringObjectivesUIFigure
        function WildlifeScoringObjectivesUIFigureCloseRequest(app, event)
            % Re-enable main app
            if isvalid(app.MainApp)
                app.MainApp.ScoringObjectivesButton.Enable = true;
                app.MainApp.NewGameButton.Enable = true;
                setGameOptionsEnableStatus(app.MainApp, true);
                setGameInteractivity(app.MainApp, true);
            end

            % Tell main app custom scoring rules if applicable
            if app.GameMode == GameModeEnum.CustomRules

            end

            delete(app);
        end

        % Value changed function: BearDropDown
        function DropDownValueChanged(app, event)
            value = event.Source.Value;
            event.Source.UserData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WildlifeScoringObjectivesUIFigure = uifigure('Visible', 'off');
            app.WildlifeScoringObjectivesUIFigure.Name = 'Wildlife Scoring Objectives';
            app.WildlifeScoringObjectivesUIFigure.Icon = fullfile(pathToMLAPP, 'Images', 'HexIcon.png');
            app.WildlifeScoringObjectivesUIFigure.CloseRequestFcn = createCallbackFcn(app, @WildlifeScoringObjectivesUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.WildlifeScoringObjectivesUIFigure);
            app.ElkImage = uiimage(app.GridLayout);
            app.ElkImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'ElkRuleA.jpg');
            app.FoxImage = uiimage(app.GridLayout);
            app.FoxImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'FoxRuleA.jpg');
            app.SalmonImage = uiimage(app.GridLayout);
            app.SalmonImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'SalmonRuleA.jpg');
            app.BearImage = uiimage(app.GridLayout);
            app.BearImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'BearRuleA.jpg');
            app.HawkImage = uiimage(app.GridLayout);
            app.HawkImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'HawkRuleA.jpg');
            app.RuleSettingPanel = uipanel(app.GridLayout);
            app.RuleSettingPanel.Title = 'Rule Setting';
            app.GridLayout2 = uigridlayout(app.RuleSettingPanel);
            app.BearDropDownLabel = uilabel(app.GridLayout2);
            app.BearDropDownLabel.Enable = 'off';
            app.BearDropDownLabel.Text = 'Bear';
            app.BearDropDown = uidropdown(app.GridLayout2);
            app.BearDropDown.Items = {'A', 'B', 'C', 'D'};
            app.BearDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.BearDropDown.Enable = 'off';
            app.BearDropDown.Value = 'A';
            app.ElkDropDownLabel = uilabel(app.GridLayout2);
            app.ElkDropDownLabel.Enable = 'off';
            app.ElkDropDownLabel.Text = 'Elk';
            app.ElkDropDown = uidropdown(app.GridLayout2);
            app.ElkDropDown.Items = {'A', 'B', 'C', 'D'};
            app.ElkDropDown.Enable = 'off';
            app.ElkDropDown.Value = 'A';
            app.SalmonDropDownLabel = uilabel(app.GridLayout2);
            app.SalmonDropDownLabel.Enable = 'off';
            app.SalmonDropDownLabel.Text = 'Salmon';
            app.SalmonDropDown = uidropdown(app.GridLayout2);
            app.SalmonDropDown.Items = {'A', 'B', 'C', 'D'};
            app.SalmonDropDown.Enable = 'off';
            app.SalmonDropDown.Value = 'A';
            app.HawkDropDownLabel = uilabel(app.GridLayout2);
            app.HawkDropDownLabel.Enable = 'off';
            app.HawkDropDownLabel.Text = 'Hawk';
            app.HawkDropDown = uidropdown(app.GridLayout2);
            app.HawkDropDown.Items = {'A', 'B', 'C', 'D'};
            app.HawkDropDown.Enable = 'off';
            app.HawkDropDown.Value = 'A';
            app.FoxDropDownLabel = uilabel(app.GridLayout2);
            app.FoxDropDownLabel.Enable = 'off';
            app.FoxDropDownLabel.Text = 'Fox';
            app.FoxDropDown = uidropdown(app.GridLayout2);
            app.FoxDropDown.Items = {'A', 'B', 'C', 'D'};
            app.FoxDropDown.Enable = 'off';
            app.FoxDropDown.Value = 'A';
            % Show the figure after all components are created
            app.WildlifeScoringObjectivesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScoreObjectiveDialog(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WildlifeScoringObjectivesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.WildlifeScoringObjectivesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WildlifeScoringObjectivesUIFigure)
        end
    end
end